classdef GraFT2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        Label2               matlab.ui.control.Label
        TabGroup             matlab.ui.container.TabGroup
        NewLoadTab           matlab.ui.container.Tab
        GridLayout2          matlab.ui.container.GridLayout
        Panel_2              matlab.ui.container.Panel
        GridLayout3          matlab.ui.container.GridLayout
        Panel_4              matlab.ui.container.Panel
        GridLayout5          matlab.ui.container.GridLayout
        WelcometoGraFTSelectfiletypebelowtobeginLabel  matlab.ui.control.Label
        NewLoadButton        matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        Label                matlab.ui.control.Label
        PATHLabel            matlab.ui.control.Label
        LoadDataPanel        matlab.ui.container.Panel
        GridLayout4          matlab.ui.container.GridLayout
        SelectFileTypePanel  matlab.ui.container.Panel
        GridLayout6          matlab.ui.container.GridLayout
        S3Button             matlab.ui.control.StateButton
        FileButton           matlab.ui.control.StateButton
        FolderButton         matlab.ui.control.StateButton
        Panel_5              matlab.ui.container.Panel
        GridLayout10         matlab.ui.container.GridLayout
        ShiftClickGroupSelectCtrlClickMultiSelectLabel  matlab.ui.control.Label
        GridLayout11         matlab.ui.container.GridLayout
        ALLButton            matlab.ui.control.Button
        SelectButton         matlab.ui.control.Button
        ListBox              matlab.ui.control.ListBox
        TextArea             matlab.ui.control.TextArea
        PreprocessTab        matlab.ui.container.Tab
        GridLayout7          matlab.ui.container.GridLayout
        ParametersTab        matlab.ui.container.Tab
        GridLayout12         matlab.ui.container.GridLayout
        ParametersPanel      matlab.ui.container.Panel
        GridLayout12_2       matlab.ui.container.GridLayout
        GridLayout13_6       matlab.ui.container.GridLayout
        Label_7              matlab.ui.control.Label
        EditField_6          matlab.ui.control.NumericEditField
        EditField_6Label     matlab.ui.control.Label
        GridLayout13_5       matlab.ui.container.GridLayout
        Label_6              matlab.ui.control.Label
        EditField_5          matlab.ui.control.NumericEditField
        EditField_5Label     matlab.ui.control.Label
        GridLayout13_4       matlab.ui.container.GridLayout
        Label_5              matlab.ui.control.Label
        EditField_4          matlab.ui.control.NumericEditField
        EditField_4Label     matlab.ui.control.Label
        GridLayout13_3       matlab.ui.container.GridLayout
        Label_4              matlab.ui.control.Label
        EditField_3          matlab.ui.control.NumericEditField
        EditField_3Label     matlab.ui.control.Label
        GridLayout13_2       matlab.ui.container.GridLayout
        Label_3              matlab.ui.control.Label
        EditField_2          matlab.ui.control.NumericEditField
        EditField_2Label     matlab.ui.control.Label
        GridLayout13         matlab.ui.container.GridLayout
        Label_2              matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        EditFieldLabel       matlab.ui.control.Label
        GraFTTab             matlab.ui.container.Tab
        GridLayout8          matlab.ui.container.GridLayout
        ResultsTab           matlab.ui.container.Tab
        SettingsTab          matlab.ui.container.Tab
        GridLayout9          matlab.ui.container.GridLayout
        GraFTAnalysisLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        fset        % app.uifigure settings
        lDir        % launch directory
        G           % GRAFT
    end
    
    methods (Access = private)
        
        function setUIUX(app)
            % background colors
            app = colorMe(app, 'DarkMode');

            % default visibilities
            app = setVis(app);
            
            % font settings
            app.fset.fontHandles = findobj(app.UIFigure, '-property', 'FontSize');
            app.fset.fontSizes = cell2mat(get(app.fset.fontHandles, 'FontSize'));
        end
        
        function setDefault(app)
            % Dir
            app.lDir = pwd;
            app.Label.Text = app.lDir;

            % GRAFT
            app.G = GRAFT();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % hide app figure
            app.UIFigure.Visible = 'off';
            
            % UI/UX
            app.setUIUX()
            
            % Default Load
            app.setDefault()

            % show app figure
            app.UIFigure.Visible = 'on';
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            % auto updates font sizes
            try
                new_width = app.UIFigure.Position(3);
                newFontSize = double(new_width)*app.fset.fontSizes/675.0;
                set(app.fset.fontHandles, {'FontSize'}, num2cell(newFontSize))
            catch 
                % first time run
                app.fset.userResolution = get(0, 'ScreenPixelsPerInch');
                app.fset.userScrnSize   = get(0, 'ScreenSize');
            end
        end

        % Value changed function: FileButton, FolderButton, S3Button
        function FolderButtonValueChanged(app, event)
            % Search for either folder or individual file. Function is used
            % for all buttons in New Load Tab.
        
            switch event.Source.Text
                case 'Folder'
                    % Visibilities/Assignments
                    app.FileButton.Visible = 'off';
                    app.S3Button.Visible = 'off';
                    app.G.Encoding.loading_type = 'Folder';
                    app.ListBox.Multiselect = 'on';
                    % Get dir
                    path = uigetdir("Select Folder to Save");
                case 'File'
                    % Visibilities/Assignments
                    app.FolderButton.Visible = 'off';
                    app.S3Button.Visible = 'off';
                    app.G.Encoding.loading_type = 'File';
                    app.ListBox.Multiselect = 'off';
                    % Get file
                    [filename, path] = uigetfile({'*.nwb;*.mat;*.tif'}, "Select File");
                    path = [path, filename];
                case 'S3'
                    % TODO %
                    % Visibilities
                    app.FolderButton.Visible = 'off';
                    app.FileButton.Visible = 'off';
                    app.G.Encoding.loading_type = 'S3';
            end

            % Back Focus
            figure(app.UIFigure);

            % check if exists
            if ~isequal(path(1), 0)
                % Encoding Update
                app.G.Encoding.data_path = path;
                % Update Text
                txt = sprintf(">>%s Selected\n", app.G.Encoding.loading_type);
                app.TextArea.Value = txt;
                % Search
                d = uiprogressdlg(app.UIFigure,'Title','Loading Selected',...
                    'Cancelable','off', 'Indeterminate','on','CancelText','Early Stop');
                d.Message = sprintf('File type - %s', event.Source.Text);
                app.G = load_app_data2(app.G);
                close(d);
                % Populate ListBox
                app.ListBox.Items = app.G.Encoding.files.Name;
                % Check Flag
                if strcmp(app.G.Encoding.flag, "")
                    app.ALLButton.Enable = 'on';
                    app.SelectButton.Enable = 'on';
                else
                    app.TextArea.Value = sprintf("%s\n%s",app.TextArea.Value, ...
                                                        app.G.Encoding.Flag);
                end
                % Enable new load
                app.NewLoadButton.Enable = 'on';
            else
                % if cancelled
                app.G.Encoding.loading_type = '';
                app.FolderButton.Visible = 'on';
                app.FileButton.Visible = 'on';
                app.S3Button.Visible = 'on';
                app.FolderButton.Value = 1;
                app.FileButton.Value = 1;
                app.S3Button.Value = 1;
                return
            end

        end

        % Button pushed function: NewLoadButton
        function NewLoadButtonPushed(app, event)
            % default visibilities
            app = setVis(app);

            % Default Load
            app.setDefault()
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;

            switch app.G.Encoding.loading_type
                case 'Folder'
                    if length(value)>1  % Multiselect
                        % Find rows with matching names
                        matchingRows = ismember(app.G.Encoding.files.Name, value);
                        
                        % Create a new table with matching rows
                        newTable = app.G.Encoding.files(matchingRows, :);

                        % Check for uniformity in columns (excluding the 'Name' column)
                        uniformityFlag = true; % Assume uniformity initially
                        nonUniformColumns = {}; % Keep track of non-uniform columns
                        
                        % Iterate over each column (excluding the 'Name' column)
                        for colIndex = 1:size(newTable, 2)
                            if ~strcmp(newTable.Properties.VariableNames{colIndex}, 'Name') % Skip the 'Name' column
                                uniqueValues = unique(newTable.(colIndex));
                                if numel(uniqueValues) > 1
                                    uniformityFlag = false; % Set flag to indicate non-uniformity
                                    nonUniformColumns = [nonUniformColumns, newTable.Properties.VariableNames{colIndex}];
                                end
                            end
                        end
                        
                        % Display warning if the selected rows are not uniform
                        if ~uniformityFlag
                            warningMsg = 'Warning: The selected rows are not uniform in the following column(s): ';
                            for i = 1:numel(nonUniformColumns)
                                warningMsg = [warningMsg, nonUniformColumns{i}, ', '];
                            end
                            warningMsg = warningMsg(1:end-2); % Remove the last comma and space
                            disp(warningMsg);
                        end
                        
                    else % Single select
                        row = app.G.Encoding.files(strcmp(app.G.Encoding.files.Name, value), :);
                        txt = sprintf("          name: %s\n" + ...
                            "                    format: %s\n" + ...
                            "                     bytes: %d\n " + ...
                            "                      size: [%dx%d]\n" + ...
                            "               colortype: %s\n", ...
                                row.Name{1}, row.Format{1}, row.FileSize(1), ...
                                row.Width(1), row.Height(1), row.ColorType{1});
                        app.TextArea.Value = sprintf('>>Folder Selected\n>>current_file\n           %s', txt);
                    end

                case 'File'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GraFTAnalysisLabel = uilabel(app.GridLayout);
            app.GraFTAnalysisLabel.Text = 'GraFT - Analysis';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.NewLoadTab = uitab(app.TabGroup);
            app.NewLoadTab.Title = 'New Load';
            app.GridLayout2 = uigridlayout(app.NewLoadTab);
            app.LoadDataPanel = uipanel(app.GridLayout2);
            app.LoadDataPanel.Title = 'Load Data';
            app.GridLayout4 = uigridlayout(app.LoadDataPanel);
            app.Panel_5 = uipanel(app.GridLayout4);
            app.GridLayout10 = uigridlayout(app.Panel_5);
            app.TextArea = uitextarea(app.GridLayout10);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'>>'; ''};
            app.ListBox = uilistbox(app.GridLayout10);
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {'Item 1'};
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.SelectButton = uibutton(app.GridLayout11, 'push');
            app.SelectButton.Text = 'Select';
            app.ALLButton = uibutton(app.GridLayout11, 'push');
            app.ALLButton.Text = 'ALL';
            app.ShiftClickGroupSelectCtrlClickMultiSelectLabel = uilabel(app.GridLayout10);
            app.ShiftClickGroupSelectCtrlClickMultiSelectLabel.Text = {'Shift + Click = Group Select'; 'Ctrl + Click = Multi-Select'};
            app.SelectFileTypePanel = uipanel(app.GridLayout4);
            app.SelectFileTypePanel.Title = 'Select File Type';
            app.GridLayout6 = uigridlayout(app.SelectFileTypePanel);
            app.FolderButton = uibutton(app.GridLayout6, 'state');
            app.FolderButton.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.FolderButton.Text = 'Folder';
            app.FileButton = uibutton(app.GridLayout6, 'state');
            app.FileButton.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.FileButton.Text = 'File';
            app.S3Button = uibutton(app.GridLayout6, 'state');
            app.S3Button.ValueChangedFcn = createCallbackFcn(app, @FolderButtonValueChanged, true);
            app.S3Button.Enable = 'off';
            app.S3Button.Text = 'S3';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.PATHLabel = uilabel(app.GridLayout3);
            app.PATHLabel.Text = 'PATH:';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = 'C:\Users\estra\Documents\MATLAB\GraFT App 623';
            app.ResetButton = uibutton(app.GridLayout3, 'push');
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.Panel_4 = uipanel(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.Panel_4);
            app.NewLoadButton = uibutton(app.GridLayout5, 'push');
            app.NewLoadButton.ButtonPushedFcn = createCallbackFcn(app, @NewLoadButtonPushed, true);
            app.NewLoadButton.Enable = 'off';
            app.NewLoadButton.Text = 'New Load';
            app.WelcometoGraFTSelectfiletypebelowtobeginLabel = uilabel(app.GridLayout5);
            app.WelcometoGraFTSelectfiletypebelowtobeginLabel.Text = 'Welcome to GraFT! Select file type below to begin.';
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Preprocess';
            app.GridLayout7 = uigridlayout(app.PreprocessTab);
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout12 = uigridlayout(app.ParametersTab);
            app.ParametersPanel = uipanel(app.GridLayout12);
            app.ParametersPanel.Title = 'Parameters';
            app.GridLayout12_2 = uigridlayout(app.ParametersPanel);
            app.GridLayout13 = uigridlayout(app.GridLayout12_2);
            app.EditFieldLabel = uilabel(app.GridLayout13);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout13, 'numeric');
            app.Label_2 = uilabel(app.GridLayout13);
            app.GridLayout13_2 = uigridlayout(app.GridLayout12_2);
            app.EditField_2Label = uilabel(app.GridLayout13_2);
            app.EditField_2Label.Text = 'Edit Field';
            app.EditField_2 = uieditfield(app.GridLayout13_2, 'numeric');
            app.Label_3 = uilabel(app.GridLayout13_2);
            app.GridLayout13_3 = uigridlayout(app.GridLayout12_2);
            app.EditField_3Label = uilabel(app.GridLayout13_3);
            app.EditField_3Label.Text = 'Edit Field';
            app.EditField_3 = uieditfield(app.GridLayout13_3, 'numeric');
            app.Label_4 = uilabel(app.GridLayout13_3);
            app.GridLayout13_4 = uigridlayout(app.GridLayout12_2);
            app.EditField_4Label = uilabel(app.GridLayout13_4);
            app.EditField_4Label.Text = 'Edit Field';
            app.EditField_4 = uieditfield(app.GridLayout13_4, 'numeric');
            app.Label_5 = uilabel(app.GridLayout13_4);
            app.GridLayout13_5 = uigridlayout(app.GridLayout12_2);
            app.EditField_5Label = uilabel(app.GridLayout13_5);
            app.EditField_5Label.Text = 'Edit Field';
            app.EditField_5 = uieditfield(app.GridLayout13_5, 'numeric');
            app.Label_6 = uilabel(app.GridLayout13_5);
            app.GridLayout13_6 = uigridlayout(app.GridLayout12_2);
            app.EditField_6Label = uilabel(app.GridLayout13_6);
            app.EditField_6Label.Text = 'Edit Field';
            app.EditField_6 = uieditfield(app.GridLayout13_6, 'numeric');
            app.Label_7 = uilabel(app.GridLayout13_6);
            app.GraFTTab = uitab(app.TabGroup);
            app.GraFTTab.Title = 'GraFT!';
            app.GridLayout8 = uigridlayout(app.GraFTTab);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout9 = uigridlayout(app.SettingsTab);
            app.Label2 = uilabel(app.GridLayout);
            app.Label2.Text = 'The details of GraFT are described in: A.S. Charles, N. Cermak, R. Affan, B. Scott, J. Schiller & G. Mishne. GraFT: Graph Filtered Temporal Dictionary Learning for Functional Neural Imaging.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraFT2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end